classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        FileTab                        matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        Panel_3                        matlab.ui.container.Panel
        EditField_2                    matlab.ui.control.EditField
        ImageNumberSpinnerLabel        matlab.ui.control.Label
        ImageNumberSpinner             matlab.ui.control.Spinner
        matButton                      matlab.ui.control.Button
        volButton                      matlab.ui.control.Button
        octbinButton                   matlab.ui.control.Button
        FileNameEditFieldLabel         matlab.ui.control.Label
        FileNameEditField              matlab.ui.control.EditField
        RotateButton                   matlab.ui.control.Button
        LoadExcelFileButton            matlab.ui.control.Button
        ManualLayerSegmentationTab     matlab.ui.container.Tab
        BoundariesPanel_2              matlab.ui.container.Panel
        ILMButton_2                    matlab.ui.control.Button
        NFLGCLButton_2                 matlab.ui.control.Button
        INLOPLButton_2                 matlab.ui.control.Button
        OPLONLButton_2                 matlab.ui.control.Button
        ONLISOSButton_2                matlab.ui.control.Button
        ISOSRPEButton_2                matlab.ui.control.Button
        BMChoroidButton_2              matlab.ui.control.Button
        IPLINLButton_2                 matlab.ui.control.Button
        GCLIPLButton_4                 matlab.ui.control.Button
        UIAxes8                        matlab.ui.control.UIAxes
        ImageNumberEditFieldLabel      matlab.ui.control.Label
        ImageNumberEditField           matlab.ui.control.NumericEditField
        ShowAllLayersandSaveButton     matlab.ui.control.Button
        AutoLayerSegmentationTab       matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        SemiAutomaticTab               matlab.ui.container.Tab
        ImageNumberEditField_5Label    matlab.ui.control.Label
        ImageNumberEditField_5         matlab.ui.control.NumericEditField
        UIAxes1                        matlab.ui.control.UIAxes
        ShowAllLayersButton            matlab.ui.control.Button
        BoundariesPanel                matlab.ui.container.Panel
        ILMButton                      matlab.ui.control.Button
        NFLGCLButton                   matlab.ui.control.Button
        IPLINLButton                   matlab.ui.control.Button
        GCLIPLButton_2                 matlab.ui.control.Button
        INLOPLButton                   matlab.ui.control.Button
        OPLONLButton                   matlab.ui.control.Button
        ONLISOSButton                  matlab.ui.control.Button
        ISOSRPEButton                  matlab.ui.control.Button
        BMChoroidButton                matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        Lamp2                          matlab.ui.control.Lamp
        Lamp3                          matlab.ui.control.Lamp
        Lamp4                          matlab.ui.control.Lamp
        Lamp5                          matlab.ui.control.Lamp
        Lamp6                          matlab.ui.control.Lamp
        Lamp7                          matlab.ui.control.Lamp
        Lamp9                          matlab.ui.control.Lamp
        Lamp8                          matlab.ui.control.Lamp
        ManualGridTab                  matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        GridEditFieldLabel             matlab.ui.control.Label
        GridEditField                  matlab.ui.control.NumericEditField
        Panel                          matlab.ui.container.Panel
        ILMCheckBox                    matlab.ui.control.CheckBox
        NFLGCLCheckBox                 matlab.ui.control.CheckBox
        GCLIPLCheckBox                 matlab.ui.control.CheckBox
        IPLINLCheckBox                 matlab.ui.control.CheckBox
        INLOPLCheckBox                 matlab.ui.control.CheckBox
        OPLONLCheckBox                 matlab.ui.control.CheckBox
        ONLISOSCheckBox                matlab.ui.control.CheckBox
        ISOSRPECheckBox                matlab.ui.control.CheckBox
        BMChoroidCheckBox              matlab.ui.control.CheckBox
        ImageNumberEditField_2Label    matlab.ui.control.Label
        ImageNumberEditField_2         matlab.ui.control.NumericEditField
        LayerCorrectionTab             matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        Panel_4                        matlab.ui.container.Panel
        ILMCheckBox_2                  matlab.ui.control.CheckBox
        NFLGCLCheckBox_2               matlab.ui.control.CheckBox
        GCLIPLCheckBox_2               matlab.ui.control.CheckBox
        IPLINLCheckBox_2               matlab.ui.control.CheckBox
        INLOPLCheckBox_2               matlab.ui.control.CheckBox
        OPLONLCheckBox_2               matlab.ui.control.CheckBox
        ONLISOSCheckBox_2              matlab.ui.control.CheckBox
        ISOSRPECheckBox_2              matlab.ui.control.CheckBox
        BMChoroidCheckBox_2            matlab.ui.control.CheckBox
        GridEditField_2Label           matlab.ui.control.Label
        GridEditField_2                matlab.ui.control.NumericEditField
        SaveLayerInfoTab               matlab.ui.container.Tab
        ShowAllLayersandSaveButton_2   matlab.ui.control.Button
        UIAxes7                        matlab.ui.control.UIAxes
        FluidSegmentationTab           matlab.ui.container.Tab
        TabGroup3                      matlab.ui.container.TabGroup
        SemiAutomaticTab_2             matlab.ui.container.Tab
        UIAxes4                        matlab.ui.control.UIAxes
        Panel_5                        matlab.ui.container.Panel
        IRFCheckBox                    matlab.ui.control.CheckBox
        SRFCheckBox                    matlab.ui.control.CheckBox
        PEDCheckBox                    matlab.ui.control.CheckBox
        DeleteButton_2                 matlab.ui.control.Button
        FinishButton_2                 matlab.ui.control.Button
        ImageNumberEditField_3Label    matlab.ui.control.Label
        ImageNumberEditField_3         matlab.ui.control.NumericEditField
        NumberofObjectsEditFieldLabel  matlab.ui.control.Label
        NumberofObjectsEditField       matlab.ui.control.NumericEditField
        ManualTab                      matlab.ui.container.Tab
        UIAxes4_2                      matlab.ui.control.UIAxes
        Panel_6                        matlab.ui.container.Panel
        IRFCheckBox_2                  matlab.ui.control.CheckBox
        SRFCheckBox_2                  matlab.ui.control.CheckBox
        PEDCheckBox_2                  matlab.ui.control.CheckBox
        DeleteButton                   matlab.ui.control.Button
        FinishButton                   matlab.ui.control.Button
        ImageNumberEditField_4Label    matlab.ui.control.Label
        ImageNumberEditField_4         matlab.ui.control.NumericEditField
        NumberofObjectsEditField_2Label  matlab.ui.control.Label
        NumberofObjectsEditField_2     matlab.ui.control.NumericEditField
        PeripapillaryTab               matlab.ui.container.Tab
        UIAxes5                        matlab.ui.control.UIAxes
        Panel_7                        matlab.ui.container.Panel
        Boundary6Button                matlab.ui.control.Button
        Boundary3Button                matlab.ui.control.Button
        VeinDetectionButton            matlab.ui.control.Button
        Boundary2Button                matlab.ui.control.Button
        Boundary4Button                matlab.ui.control.Button
        Boundary3_finalButton          matlab.ui.control.Button
        Boundary5Button                matlab.ui.control.Button
        Boundary7Button                matlab.ui.control.Button
        Boundary8Button                matlab.ui.control.Button
        Boundary1Button                matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        ShowAllLayersandSaveButton_3   matlab.ui.control.Button
    end


    properties (Access = public)
       n=1% Description
       p=1
       g=10
       g1=10
       I
       M
       N
       K
       contourxn1
       contouryn1
       contourxn2
       contouryn2
       contourxn3
       contouryn3
       contourxn4
       contouryn4
       contourxn5
       contouryn5
       contourxn6
       contouryn6
       contourxn7
       contouryn7
       contourxn8
       contouryn8
       contourxn9
       contouryn9
       contourx_final
       del1=0;
       del2=0
       ii=0
       f
       ff
       n1=1
       n2=1
       n3=1
       n4=1
       Imb
       w 
       z
       IRFp
       SRFp
       InterRFp
       PEDp
       IRFmp
       SRFmp
       InterRFmp
       PEDmp
       d
       Ig
       Ig1
       Ig2
       Ig3
       pcontourxn1
       pcontourxn2
       pcontourxn3
       pcontourxn4
       pcontourxn5
       pcontourxn6
       pcontourxn7
       pcontourxn8
       u=0
       Phi
       r=0
       y1
       y2
       y3
       y4
       y5
       y6
       y7
       y8
       y9
       contoury_final
       file
       num


    end

    methods (Access = private)
    
        function results = func(app)
            
        end
        
    end


    methods (Access = private)

        % Value changed function: ImageNumberSpinner
        function ImageNumberSpinnerValueChanged(app, event)
            value = app.ImageNumberSpinner.Value;
            app.n=value;
            app.UIAxes.XAxis.TickLabels={};
%             app.UIAxes.YAxis.TickLabels={};
            imshow(app.I(:,:,app.n),'Parent',app.UIAxes);
            % app.UIAxes.XLim=[0 app.I.XData(2)];
            % app.UIAxes.YLim=[0 app.I.YData(2)];
        end

        % Value changed function: ImageNumberEditField_5
        function ImageNumberEditField_5ValueChanged(app, event)
            value = app.ImageNumberEditField_5.Value;
            app.ImageNumberEditField_5.Limits = [0 app.K];
            app.p=value;
            imshow(app.I(:,:,app.p),'Parent',app.UIAxes1);
            app.contourxn1=NaN(app.N,1);
            app.contourxn2=NaN(app.N,1);
            app.contourxn3=NaN(app.N,1);
            app.contourxn4=NaN(app.N,1);
            app.contourxn5=NaN(app.N,1);
            app.contourxn6=NaN(app.N,1);
            app.contourxn7=NaN(app.N,1);
            app.contourxn8=NaN(app.N,1);
            app.contourxn9=NaN(app.N,1);
            app.Lamp.Color  = [1 0.7843 0];
            app.Lamp2.Color = [1 0 0];
            app.Lamp3.Color = [1 0 0];
            app.Lamp4.Color = [1 0 0];
            app.Lamp5.Color = [1 0 0];
            app.Lamp6.Color = [1 0 0];
            app.Lamp7.Color = [1 0 0];
            app.Lamp8.Color = [1 0 0];
            app.Lamp9.Color = [1 0 0];
        end

        % Button pushed function: ShowAllLayersButton
        function ShowAllLayersButtonPushed(app, event)
            app.contourx_final=[app.contourxn1';app.contourxn2';app.contourxn3';app.contourxn4';app.contourxn5';app.contourxn6';app.contourxn7';app.contourxn8';app.contourxn9'];
            hold(app.UIAxes1, 'on');
            imshow(app.I(:,:,app.p),'Parent',app.UIAxes1)
            for k=1:8 
             for j=1:app.N
                 if app.contourx_final(k,j)>app.contourx_final(k+1,j) || app.contourx_final(k,j)==app.contourx_final(k+1,j)
                    app.contourx_final(k+1,j)=app.contourx_final(k,j)+1;
                end
             end
            end

            plot(app.UIAxes1,1:app.N,app.contourx_final(1,:),'g','LineWidth',0.5);
            plot(app.UIAxes1,1:app.N,app.contourx_final(2,:),'y','LineWidth',0.5);
            plot(app.UIAxes1,1:app.N,app.contourx_final(3,:),'b','LineWidth',0.5);
            plot(app.UIAxes1,1:app.N,app.contourx_final(4,:),'m','LineWidth',0.5);
            plot(app.UIAxes1,1:app.N,app.contourx_final(5,:),'r','LineWidth',0.5);
            plot(app.UIAxes1,1:app.N,app.contourx_final(6,:),'g','LineWidth',0.5);
            plot(app.UIAxes1,1:app.N,app.contourx_final(7,:),'color','red','LineWidth',0.5);
            plot(app.UIAxes1,1:app.N,app.contourx_final(8,:),'color','y','LineWidth',0.5);
            plot(app.UIAxes1,1:app.N,app.contourx_final(9,:),'c','LineWidth',0.5); 
        end

        % Value changed function: ILMCheckBox
        function ILMCheckBoxValueChanged(app, event)
            value = app.ILMCheckBox.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'linear');
            [app.contouryn1,app.contourxn1]=resize_contour(a,b,12,app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes2)
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2,app.contouryn1,app.contourxn1,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ILM.mat');
            layer1_auto = app.contourxn1;
            save(name, 'layer1_auto')
        end

        % Value changed function: NFLGCLCheckBox
        function NFLGCLCheckBoxValueChanged(app, event)
            value = app.NFLGCLCheckBox.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'linear');
            [app.contouryn2,app.contourxn2]=resize_contour(a,b,12,app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes2)
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2,app.contouryn2,app.contourxn2,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/NFL-GCL.mat');
            layer2_auto = app.contourxn2;
            save(name, 'layer2_auto')
        end

        % Value changed function: GCLIPLCheckBox
        function GCLIPLCheckBoxValueChanged(app, event)
            value = app.GCLIPLCheckBox.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'linear');
            [app.contouryn3,app.contourxn3]=resize_contour(a,b,12,app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes2)
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2,app.contouryn3,app.contourxn3,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/GCL-IPL.mat');
            layer3_auto = app.contourxn3;
            save(name, 'layer3_auto')
        end

        % Value changed function: IPLINLCheckBox
        function IPLINLCheckBoxValueChanged(app, event)
            value = app.IPLINLCheckBox.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'linear');
            [app.contouryn4,app.contourxn4]=resize_contour(a,b,12,app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes2)
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2,app.contouryn4,app.contourxn4,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/IPL-INL.mat');
            layer4_auto= app.contourxn4;
            save(name, 'layer4_auto')
        end

        % Value changed function: INLOPLCheckBox
        function INLOPLCheckBoxValueChanged(app, event)
            value = app.INLOPLCheckBox.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'linear');
            [app.contouryn5,app.contourxn5]=resize_contour(a,b,12,app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes2)
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2,app.contouryn5,app.contourxn5,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/INL-OPL.mat');
            layer5_auto = app.contourxn5;
            save(name, 'layer5_auto')
        end

        % Value changed function: OPLONLCheckBox
        function OPLONLCheckBoxValueChanged(app, event)
            value = app.OPLONLCheckBox.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'linear');
            [app.contouryn6,app.contourxn6]=resize_contour(a,b,12,app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes2)
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2,app.contouryn6,app.contourxn6,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/OPL-ONL.mat');
            layer6_auto = app.contourxn6;
            save(name, 'layer6_auto')
        end

        % Value changed function: ONLISOSCheckBox
        function ONLISOSCheckBoxValueChanged(app, event)
            value = app.ONLISOSCheckBox.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'linear');
            [app.contouryn7,app.contourxn7]=resize_contour(a,b,12,app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes2)
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2,app.contouryn7,app.contourxn7,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ONL-ISOS.mat');
            layer7_auto = app.contourxn7;
            save(name, 'layer7_auto')
        end

        % Value changed function: ISOSRPECheckBox
        function ISOSRPECheckBoxValueChanged(app, event)
            value = app.ISOSRPECheckBox.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'linear');
            [app.contouryn8,app.contourxn8]=resize_contour(a,b,12,app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes2)
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2,app.contouryn8,app.contourxn8,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ISOS-RPE.mat');
            layer8_auto = app.contourxn8;
            save(name, 'layer8_auto')
        end

        % Value changed function: BMChoroidCheckBox
        function BMChoroidCheckBoxValueChanged(app, event)
            value = app.BMChoroidCheckBox.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'linear');
            [app.contouryn9,app.contourxn9]=resize_contour(a,b,12,app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes2)
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2,app.contouryn9,app.contourxn9,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/BM-Choroid.mat');
            layer9_auto = app.contourxn9;
            save(name, 'layer9_auto')
        end

        % Value changed function: GridEditField
        function GridEditFieldValueChanged(app, event)
            value = app.GridEditField.Value;
            app.g=value;
        end

        % Value changed function: GridEditField_2
        function GridEditField_2ValueChanged(app, event)
            value = app.GridEditField_2.Value;
            app.g1=value;
        end

        % Value changed function: ILMCheckBox_2
        function ILMCheckBox_2ValueChanged(app, event)
            value = app.ILMCheckBox_2.Value;
            if value==true
            [app.contourxn1,app.contouryn1]=manual_correction(app.I(:,:,app.p),app.contourxn1,app.contouryn1,app.g1);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes3)
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3,app.contouryn1,app.contourxn1,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ILM.mat');
            layer1_auto = app.contourxn1;
            save(name, 'layer1_auto')
        end

        % Value changed function: NFLGCLCheckBox_2
        function NFLGCLCheckBox_2ValueChanged(app, event)
            value = app.NFLGCLCheckBox_2.Value;
            if value==true
            [app.contourxn2,app.contouryn2]=manual_correction(app.I(:,:,app.p),app.contourxn2,app.contouryn2,app.g1);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes3)
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3,app.contouryn2,app.contourxn2,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/NFL-GCL.mat');
            layer2_auto = app.contourxn2;
            save(name, 'layer2_auto')
        end

        % Value changed function: GCLIPLCheckBox_2
        function GCLIPLCheckBox_2ValueChanged(app, event)
            value = app.GCLIPLCheckBox_2.Value;
            if value==true
            [app.contourxn3,app.contouryn3]=manual_correction(app.I(:,:,app.p),app.contourxn3,app.contouryn3,app.g1);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes3)
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3,app.contouryn3,app.contourxn3,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/GCL-IPL.mat');
            layer3_auto = app.contourxn3;
            save(name, 'layer3_auto')
        end

        % Value changed function: IPLINLCheckBox_2
        function IPLINLCheckBox_2ValueChanged(app, event)
            value = app.IPLINLCheckBox_2.Value;
            if value==true
            [app.contourxn4,app.contouryn4]=manual_correction(app.I(:,:,app.p),app.contourxn4,app.contouryn4,app.g1);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes3)
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3,app.contouryn4,app.contourxn4,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/IPL-INL.mat');
            layer4_auto = app.contourxn4;
            save(name, 'layer4_auto')
        end

        % Value changed function: INLOPLCheckBox_2
        function INLOPLCheckBox_2ValueChanged(app, event)
            value = app.INLOPLCheckBox_2.Value;
            if value==true 
            [app.contourxn5,app.contouryn5]=manual_correction(app.I(:,:,app.p),app.contourxn5,app.contouryn5,app.g1);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes3)
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3,app.contouryn5,app.contourxn5,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/INL-OPL.mat');
            layer5_auto = app.contourxn5;
            save(name, 'layer5_auto')
        end

        % Value changed function: OPLONLCheckBox_2
        function OPLONLCheckBox_2ValueChanged(app, event)
            value = app.OPLONLCheckBox_2.Value;
            if value==true
            [app.contourxn6,app.contouryn6]=manual_correction(app.I(:,:,app.p),app.contourxn6,app.contouryn6,app.g1);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes3)
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3,app.contouryn6,app.contourxn6,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/OPL-ONL.mat');
            layer6_auto = app.contourxn6;
            save(name, 'layer6_auto')
        end

        % Value changed function: ONLISOSCheckBox_2
        function ONLISOSCheckBox_2ValueChanged(app, event)
            value = app.ONLISOSCheckBox_2.Value;
            if value==true 
            [app.contourxn7,app.contouryn7]=manual_correction(app.I(:,:,app.p),app.contourxn7,app.contouryn7,app.g1);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes3)
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3,app.contouryn7,app.contourxn7,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ONL-ISOS.mat');
            layer7_auto = app.contourxn7;
            save(name, 'layer7_auto')
        end

        % Value changed function: ISOSRPECheckBox_2
        function ISOSRPECheckBox_2ValueChanged(app, event)
            value = app.ISOSRPECheckBox_2.Value;
            if value==true
            [app.contourxn8,app.contouryn8]=manual_correction(app.I(:,:,app.p),app.contourxn8,app.contouryn8,app.g1);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes3)
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3,app.contouryn8,app.contourxn8,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ISOS-RPE.mat');
            layer8_auto = app.contourxn8;
            save(name, 'layer8_auto')
        end

        % Value changed function: BMChoroidCheckBox_2
        function BMChoroidCheckBox_2ValueChanged(app, event)
            value = app.BMChoroidCheckBox_2.Value;
            if value==true
            [app.contourxn9,app.contouryn9]=manual_correction(app.I(:,:,app.p),app.contourxn9,app.contouryn9,app.g1);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes3)
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3,app.contouryn9,app.contourxn9,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/BM-Choroid.mat');
            layer9_auto = app.contourxn9;
            save(name, 'layer9_auto')
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.EditField_2.Value = strcat('/ ',num2str(app.K));
        end

        % Button pushed function: matButton
        function matButtonPushed(app, event)
                 s=uiimport('-file');
                 s=struct2cell(s);
                 app.I=s{1};
                
                 [app.M,app.N,app.K]=size(app.I);
                 imshow(app.I(:,:,1),'Parent',app.UIAxes);
                 EditField_2ValueChanged(app, event);
                 app.ImageNumberSpinner.Value=1;
                 if app.K==1
                    app.ImageNumberSpinner.Limits = [0 app.K];
                 else
                    app.ImageNumberSpinner.Limits = [1 app.K];
                 end
        end

        % Button pushed function: volButton
        function volButtonPushed(app, event)
                  [file,path]=uigetfile('.vol');
                  address=strcat(path,file);
                  [NumBScans,Im_SLO_Image,OCTs,header,BScanHeader]= read_VOL_func(address);
                  app.I=OCTs;
                  [app.M,app.N,app.K]=size(app.I);
                  imshow(app.I(:,:,1),'Parent',app.UIAxes);
                  EditField_2ValueChanged(app, event);
                  app.ImageNumberSpinner.Value=1;
                  if app.K==1
                    app.ImageNumberSpinner.Limits = [0 app.K];
                 else
                    app.ImageNumberSpinner.Limits = [1 app.K];
                 end
        end

        % Button pushed function: octbinButton
        function octbinButtonPushed(app, event)
              [file,path]=uigetfile('.octbin');
              address=strcat(path,file);
              [s] = readbin(address);
              a=struct2cell(s);
              b=struct2cell(a{2,1});
              c=struct2cell(b{2,1});
              d=struct2cell(c{2,1});
              e=d{1,1};
              for i=1:numel(e)
                  d=struct2cell(e{1,i});
                  Im=d{2,1};
                  OCTs(:,:,i)=Im;
              end
         
              app.I=OCTs;
              [app.M,app.N,app.K]=size(app.I); 
              imshow(app.I(:,:,1),'Parent',app.UIAxes);
              EditField_2ValueChanged(app, event);
              app.ImageNumberSpinner.Value=1;
              if app.K==1
                    app.ImageNumberSpinner.Limits = [0 app.K];
              else
                    app.ImageNumberSpinner.Limits = [1 app.K];
              end
        end

        % Button pushed function: ILMButton
        function ILMButtonPushed(app, event)
                    %1.live wire on layer1
                    [Gx,Gy] = imgradientxy(app.I(:,:,app.p),'sobel');
                    hold(app.UIAxes1, 'on');
                    [r_contour1,contoury1,contourx1]=lwcontour(Gy,app.I(:,:,app.p),1,1000);
                    [app.contouryn1,app.contourxn1]=resize_contour(contoury1,contourx1,app.num(1,1),app.I(:,:,app.p));
                    imshow(app.I(:,:,app.p),'Parent',app.UIAxes1)
                    plot(app.UIAxes1,app.contouryn1,app.contourxn1,'color','[0 1 0.8]','linewidth',1);
                    app.Lamp.Color = 'green';
                    app.Lamp2.Color = [1 0.7843 0];
                    %2.live wire on layer1
                    name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                    mkdir(name)
                    name=strcat(name,'/ILM.mat');
                    layer1_auto = app.contourxn1;
                    save(name, 'layer1_auto')
        end

        % Button pushed function: NFLGCLButton
        function NFLGCLButtonPushed(app, event)
                    % 1.live wire on layer2(Dir)
                    [Gx,Gy] = imgradientxy(app.I(:,:,app.p),'sobel');
                    [mag,dir]=imgradient(Gx,Gy);
                    se=strel('line',app.num(2,3),app.num(2,4));
                    dir_er=imerode(dir,se);
                    hold(app.UIAxes1, 'on');
                    [r_contour2,contoury2,contourx2]=lwcontour(dir_er,app.I(:,:,app.p),1,1000);
                    [app.contouryn2,app.contourxn2]=resize_contour(contoury2,contourx2,app.num(2,1),app.I(:,:,app.p));
                    imshow(app.I(:,:,app.p),'Parent',app.UIAxes1)
                    plot(app.UIAxes1,app.contouryn2,app.contourxn2,'y');
                    app.Lamp2.Color = 'green';
                    app.Lamp3.Color = [1 0.7843 0];
                    %2.live wire on layer2(Dir)  
                    name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                    mkdir(name)
                    name=strcat(name,'/NFL-GCL.mat');
                    layer2_auto = app.contourxn2;
                    save(name, 'layer2_auto')
        end

        % Button pushed function: IPLINLButton
        function IPLINLButtonPushed(app, event)
                %1.live wire on layer4
                [Gx,Gy] = imgradientxy(app.I(:,:,app.p),'sobel');
                [mag,dir]=imgradient(Gx,Gy);
                se=strel('rectangle',[app.num(4,3) app.num(4,4)]); 
                dir_er=imerode(dir,se);
                hold(app.UIAxes1, 'on');
                [r_contour4,contoury4,contourx4]=lwcontour(dir_er,app.I(:,:,app.p),1,1000);
                [app.contouryn4,app.contourxn4]=resize_contour(contoury4,contourx4,app.num(4,1),app.I(:,:,app.p));
                imshow(app.I(:,:,app.p),'Parent',app.UIAxes1)
                plot(app.UIAxes1,app.contouryn4,app.contourxn4,'color','[1 0.4 0.6]' );
                app.Lamp3.Color = 'green';
                app.Lamp4.Color = [1 0.7843 0];
                %2.live wire on layer4
                name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                mkdir(name)
                name=strcat(name,'/IPL-INL.mat');
                layer4_auto = app.contourxn4;
                save(name, 'layer4_auto')
        end

        % Button pushed function: GCLIPLButton_2
        function GCLIPLButton_2Pushed(app, event)
                    %1.automatic layer3 finding
                    [Gx,Gy] = imgradientxy(app.I(:,:,app.p),'sobel');
                    app.contourxn3=app.contourxn2;
                    for i=1:app.N
                    v=Gy(app.contourxn2(i):app.contourxn4(i),i);
                    r=find(v==max(v));   
                    if isempty(r)
                        app.contourxn3(i)=app.contourxn2(i);
                    else
                    app.contourxn3(i)=app.contourxn2(i)+max(r)-1; 
                    end
                    end
                    [app.contouryn3,app.contourxn3]=resize_contour(1:app.N,app.contourxn3,app.num(3,1),app.I(:,:,app.p));
                    imshow(app.I(:,:,app.p),'Parent',app.UIAxes1)
                    plot(app.UIAxes1,app.contouryn3,app.contourxn3,'g');
                    app.Lamp4.Color = 'green';
                    app.Lamp5.Color = [1 0.7843 0];
                    %2.automatic layer3 finding
                    name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                    mkdir(name)
                    name=strcat(name,'/GCL-IPL.mat');
                    layer3_auto = app.contourxn3;
                    save(name, 'layer3_auto')
        end

        % Button pushed function: INLOPLButton
        function INLOPLButtonPushed(app, event)
                   %1.live wire on layer5
                    [Gx,Gy] = imgradientxy(app.I(:,:,app.p),'sobel');
                    [mag,dir]=imgradient(Gx,Gy);
                    se=strel('rectangle',[app.num(5,3) app.num(5,4)]);
                    dir_di=imdilate(dir,se);
                    hold(app.UIAxes1, 'on');
                    [r_contour5,contoury5,contourx5]=lwcontour(dir_di,app.I(:,:,app.p),1,1000);
                    [app.contouryn5,app.contourxn5]=resize_contour(contoury5,contourx5,app.num(5,1),app.I(:,:,app.p));
                    imshow(app.I(:,:,app.p),'Parent',app.UIAxes1)
                    plot(app.UIAxes1,app.contouryn5,app.contourxn5,'color','[0.6 1 0.2]');
                    app.Lamp5.Color = 'green';
                    app.Lamp6.Color = [1 0.7843 0];
                    %2.live wire on layer5
                    name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                    mkdir(name)
                    name=strcat(name,'/INL-OPL.mat');
                    layer5_auto = app.contourxn5;
                    save(name, 'layer5_auto')
        end

        % Button pushed function: OPLONLButton
        function OPLONLButtonPushed(app, event)
                    %1.live wire on layer6
                    [Gx,Gy] = imgradientxy(app.I(:,:,app.p),'sobel');
                    [mag,dir]=imgradient(Gx,Gy);
                    se=strel('line',app.num(6,3),app.num(6,4));
                    dir_er=imerode(dir,se);
                    hold(app.UIAxes1, 'on');
                    [r_contour6,contoury6,contourx6]=lwcontour(dir_er,app.I(:,:,app.p),1,1000); 
                    [app.contouryn6,app.contourxn6]=resize_contour(contoury6,contourx6,app.num(6,1),app.I(:,:,app.p));
                    imshow(app.I(:,:,app.p),'Parent',app.UIAxes1)
                    plot(app.UIAxes1,app.contouryn6,app.contourxn6,'m');
                    app.Lamp6.Color = 'green';
                    app.Lamp7.Color = [1 0.7843 0];
                    %2.live wire on layer6
                    name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                    mkdir(name)
                    name=strcat(name,'/OPL-ONL.mat');
                    layer6_auto = app.contourxn6;
                    save(name, 'layer6_auto')
        end

        % Button pushed function: ONLISOSButton
        function ONLISOSButtonPushed(app, event)
                    %1.live wire on layer7
                    I_canny=edge(app.I(:,:,app.p),'canny',app.num(7,6),app.num(7,7));
                    I_canny=~I_canny;
                    se=strel('line',app.num(7,3),app.num(7,4));
                    I_canny_di=imerode(I_canny,se);
                    hold(app.UIAxes1, 'on');
                    [r_contour7,contoury7,contourx7] = lwcontour(I_canny_di,app.I(:,:,app.p),1,500);
                    [app.contouryn7,app.contourxn7]=resize_contour(contoury7,contourx7,app.num(7,1),app.I(:,:,app.p));
                    imshow(app.I(:,:,app.p),'Parent',app.UIAxes1)
                    plot(app.UIAxes1,app.contouryn7,app.contourxn7,'color','g');
                    app.Lamp7.Color = 'green';
                    app.Lamp8.Color = [1 0.7843 0];
                    %2.live wire on layer7
                    name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                    mkdir(name)
                    name=strcat(name,'/ONL-ISOS.mat');
                    layer7_auto = app.contourxn7;
                    save(name, 'layer7_auto')
        end

        % Button pushed function: ISOSRPEButton
        function ISOSRPEButtonPushed(app, event)
                    %1.live wire on layer8
                    [Gx,Gy] = imgradientxy(app.I(:,:,app.p),'sobel');
                    [mag,dir]=imgradient(Gx,Gy);
                    dirblack=black(round(app.contourxn7'),app.contouryn7,dir);
                    hold(app.UIAxes1, 'on');
                    [r_contour8,contoury8,contourx8]=lwcontour(dirblack,app.I(:,:,app.p),1,500);
                    [app.contouryn8,app.contourxn8]=resize_contour(contoury8,contourx8,app.num(8,1),app.I(:,:,app.p));
                    imshow(app.I(:,:,app.p),'Parent',app.UIAxes1)
                    plot(app.UIAxes1,app.contouryn8,app.contourxn8,'color','red');
                    app.Lamp8.Color = 'green';
                    app.Lamp9.Color = [1 0.7843 0];
                    %2.live wire on layer8
                    name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                    mkdir(name)
                    name=strcat(name,'/ISOS-RPE.mat');
                    layer8_auto = app.contourxn8;
                    save(name, 'layer8_auto')
        end

        % Button pushed function: BMChoroidButton
        function BMChoroidButtonPushed(app, event)
                    %1.live wire on layer9
                    I_black=black412(round(app.contourxn7'),app.contouryn7,app.I(:,:,app.p));
                    hold(app.UIAxes1, 'on');
                    [r_contour9,contoury9,contourx9] = lwcontour(I_black,app.I(:,:,app.p),1,500);
                    [app.contouryn9,app.contourxn9]=resize_contour(contoury9,contourx9,app.num(9,1),app.I(:,:,app.p));
                    imshow(app.I(:,:,app.p),'Parent',app.UIAxes1)
                    plot(app.UIAxes1,app.contouryn9,app.contourxn9,'c');
                    app.Lamp9.Color = 'green';
                    %2.live wire on layer9
                    name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                    mkdir(name)
                    name=strcat(name,'/BM-Choroid.mat');
                    layer9_auto = app.contourxn9;
                    save(name, 'layer9_auto')
        end

        % Value changed function: IRFCheckBox_2
        function IRFCheckBox_2ValueChanged(app, event)
            value = app.IRFCheckBox_2.Value;
            if value==true
                app.IRFmp=[];
                app.ii=0;
                app.f=0;
                app.NumberofObjectsEditField_2.Value=0;
                imshow(app.I(:,:,app.n2)); 
                app.Imb=imbinarize(app.I(:,:,app.n2));
                t=app.Imb.*0;
                while (1)                
                   app.ii=app.ii+1; 
                   h(app.ii)=imfreehand(gca,'closed',false);
                   h(app.ii).Deletable=false;
                   if app.del1==1
                        app.ii=app.ii-1;
                        h(app.ii).Deletable=true;
                        delete(h(app.ii));
                        delete(app.ff)  
                        
                        t=t-mask ;
                       % app.numberofobjectsEditField_2.Value=app.ii-1;           
                        app.IRFmp{1,app.ii}=[];
                        app.del1=0;
                        h(app.ii)=h(app.ii+1);
                    end
                    if app.f==1 
                        app.f=0;                         
                        delete(gcf)                
                        app.ii=app.ii-1;
                        break 
                   end
                   
                   xy=getPosition(h(app.ii));
                    x=xy(:,1);
                    y=xy(:,2);
                    mask=createMask(h(app.ii));
                    app.z.contourx=x;
                    app.z.contoury=y;
                    app.z.mask=mask;
                    app.IRFmp{1,app.ii}=app.z;
                    app.NumberofObjectsEditField_2.Value=app.ii;                   
                    t=mask+t ;
                    imshow(app.I(:,:,app.n2),'parent',app.UIAxes4_2);
                    hold(app.UIAxes4_2,'on');
                    app.ff=plot(app.UIAxes4_2,x,y,'r','linewidth',2); 
                 end
                    app.IRFmp{1,app.ii+1}=t;
                    IRFm=app.IRFmp;
                    name=strcat(app.file,'/fluid/manual/','Bscan-num',num2str(app.n2));
                    mkdir(name)
                    name1=strcat(name,'/IRFm.mat');
                    save(name1, 'IRFm')
                    
%                     uisave('IRFm','IRFm.mat');
                    for k=1:app.ii
                        hold(app.UIAxes4_2,'on');
                        plot(app.UIAxes4_2,app.IRFmp{1,k}.contourx,app.IRFmp{1,k}.contoury,'r','linewidth',2);
                    end
                    
                    fig = figure('Visible', false);
                    copyUIAxes(app.UIAxes4_2,fig);
                    axis off
                    saveas(fig, fullfile(name, 'final image with IRF_manual'), 'jpg');
                    delete(fig);
            end 
        end

        % Value changed function: SRFCheckBox_2
        function SRFCheckBox_2ValueChanged(app, event)
            value = app.SRFCheckBox_2.Value;
            if value==true
                app.SRFmp=[];
                app.ii=0;
                app.f=0;
                app.NumberofObjectsEditField_2.Value=0;
                imshow(app.I(:,:,app.n2)); 
                app.Imb=imbinarize(app.I(:,:,app.n2));
                t=app.Imb.*0;
                while (1)                
                    app.ii=app.ii+1; 
                    h(app.ii)=imfreehand(gca,'closed',false);
                    h(app.ii).Deletable=false;
                    if app.del1==1
                        app.ii=app.ii-1;
                        h(app.ii).Deletable=true;
                        delete(h(app.ii));
                        delete(app.ff) 
                        t=t-mask ;
                       % app.numberofobjectsEditField_2.Value=app.i-1;           
                         app.SRFmp{1,app.ii}=[];
                         app.del1=0;
                         h(app.ii)=h(app.ii+1);
                      
                    end
                    if app.f==1 
                        app.f=0;                         
                        delete(gcf)                
                        app.ii=app.ii-1;
                        break 
                    end
                    xy=getPosition(h(app.ii));
                    x=xy(:,1);
                    y=xy(:,2);
                    mask=createMask(h(app.ii));
                    app.z.contourx=x;
                    app.z.contoury=y;
                    app.z.mask=mask;
                    app.SRFmp{1,app.ii}=app.z;
                    app.NumberofObjectsEditField_2.Value=app.ii;                   
                    t=mask+t ;
                    imshow(app.I(:,:,app.n2),'parent',app.UIAxes4_2);
                    hold(app.UIAxes4_2,'on');
                 app.ff=plot(app.UIAxes4_2,x,y,'r','linewidth',2); 
                end
                app.SRFmp{1,app.ii+1}=t;
                SRFm=app.SRFmp;
                name=strcat(app.file,'/fluid/manual/','Bscan-num',num2str(app.n2));
                mkdir(name)
                name1=strcat(name,'/SRFm.mat');
                save(name1, 'SRFm')
%                 uisave('SRFm','SRFm.mat');
                for k=1:app.ii
                    hold(app.UIAxes4_2,'on');
                    plot(app.UIAxes4_2,app.SRFmp{1,k}.contourx,app.SRFmp{1,k}.contoury,'r','linewidth',2);
                end
                
                fig = figure('Visible', false);
                copyUIAxes(app.UIAxes4_2,fig);
                axis off
                saveas(fig, fullfile(name, 'final image with SRF_manual'), 'jpg');
                delete(fig);
             end
        end

        % Value changed function: PEDCheckBox_2
        function PEDCheckBox_2ValueChanged(app, event)
            value = app.PEDCheckBox_2.Value;
            if value==true
                app.PEDmp=[];
                app.ii=0;
                app.f=0;
                app.NumberofObjectsEditField_2.Value=0;
                imshow(app.I(:,:,app.n2)); 
                app.Imb=imbinarize(app.I(:,:,app.n2));
                t=app.Imb.*0;
                while (1)                
                    app.ii=app.ii+1; 
                    h(app.ii)=imfreehand(gca,'closed',false);
                    h(app.ii).Deletable=false;
                    if app.del1==1
                        app.ii=app.ii-1;
                        h(app.ii).Deletable=true;
                        delete(h(app.ii));
                        delete(app.ff)
                        t=t-mask ;
                       % app.numberofobjectsEditField_2.Value=app.i-1;           
                         app.PEDmp{1,app.ii}=[];
                         app.del1=0;
                         h(app.ii)=h(app.ii+1);
                    end
                    if app.f==1 
                        app.f=0;                         
                        delete(gcf)                
                        app.ii=app.ii-1;
                        break 
                   end
                    xy=getPosition(h(app.ii));
                    x=xy(:,1);
                    y=xy(:,2);
                    mask=createMask(h(app.ii));
                    app.z.contourx=x;
                    app.z.contoury=y;
                    app.z.mask=mask;
                    app.PEDmp{1,app.ii}=app.z;
                    app.NumberofObjectsEditField_2.Value=app.ii;                   
                    t=mask+t ;
                    imshow(app.I(:,:,app.n2),'parent',app.UIAxes4_2);
                    hold(app.UIAxes4_2,'on');
                 app.ff=plot(app.UIAxes4_2,x,y,'r','linewidth',2); 
                end
                app.PEDmp{1,app.ii+1}=t;
                PEDm=app.PEDmp;
                name=strcat(app.file,'/fluid/manual/','Bscan-num',num2str(app.n2));
                mkdir(name)
                name1=strcat(name,'/PEDm.mat');
                save(name1, 'PEDm')
%                 uisave('PEDm','PEDm.mat');
                for k=1:app.ii
                    hold(app.UIAxes4_2,'on');
                    plot(app.UIAxes4_2,app.PEDmp{1,k}.contourx,app.PEDmp{1,k}.contoury,'r','linewidth',2);
                end
                
                fig = figure('Visible', false);
                copyUIAxes(app.UIAxes4_2,fig);
                axis off
                saveas(fig, fullfile(name, 'final image with PED fluid_manual'), 'jpg');
                delete(fig);
            end
        end

        % Value changed function: ImageNumberEditField_4
        function ImageNumberEditField_4ValueChanged(app, event)
            value = app.ImageNumberEditField_4.Value;
            app.NumberofObjectsEditField_2.Value=0;
            app.ImageNumberEditField_4.Limits = [1 app.K];
            app.n2=value;
            %app.I(:,:,app.n2)=medfilt2(app.I(:,:,app.n2));
            %app.I(:,:,app.n2)=imadjust(app.I(:,:,app.n2),[0.3 0.7],[0.1 0.9],0.8);
            imshow(app.I(:,:,app.n2),'parent',app.UIAxes4_2);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.del1=true;
        end

        % Value changed function: ImageNumberEditField_3
        function ImageNumberEditField_3ValueChanged(app, event)
            value = app.ImageNumberEditField_3.Value;
            app.NumberofObjectsEditField.Value=0;
            app.ImageNumberEditField_3.Limits = [1 app.K];
            app.n1=value;
            %app.I(:,:,app.n1)=medfilt2(app.I(:,:,app.n1));
            %app.I(:,:,app.n1)=imadjust(app.I(:,:,app.n1),[0.3 0.7],[0.1 0.9],0.8);
%             app.UIAxes4.XAxis.TickLabels={};
%             app.UIAxes4.YAxis.TickLabels={};
            imshow(app.I(:,:,app.n1),'Parent',app.UIAxes4);
%             imshow(app.I(:,:,app.n1),'parent',app.UIAxes4)
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            app.f=1;
        end

        % Value changed function: NumberofObjectsEditField_2
        function NumberofObjectsEditField_2ValueChanged(app, event)
            value = app.NumberofObjectsEditField_2.Value;
            
        end

        % Value changed function: NumberofObjectsEditField
        function NumberofObjectsEditFieldValueChanged(app, event)
            value = app.NumberofObjectsEditField.Value;
            
        end

        % Button pushed function: DeleteButton_2
        function DeleteButton_2Pushed(app, event)
           app.del2=true; 
        end

        % Button pushed function: FinishButton_2
        function FinishButton_2Pushed(app, event)
            app.f=1;
            
        end

        % Value changed function: IRFCheckBox
        function IRFCheckBoxValueChanged(app, event)
            value = app.IRFCheckBox.Value;
            if value==true
                app.IRFp=[];
                imshow(app.I(:,:,app.n1),'parent',app.UIAxes2);
                app.NumberofObjectsEditField.Value=0;
                app.ii=0;
                
                I_denoise=app.I;
                I_denoise(:,:,app.n1)=medfilt2(app.I(:,:,app.n1));
                I_denoise(:,:,app.n1)=imadjust(app.I(:,:,app.n1),[0.3 0.7],[0.1 0.9],0.8);
         
                I_canny=edge(I_denoise(:,:,app.n1),'canny',app.num(11,6),app.num(11,7));
                se=strel('rectangle',[app.num(11,3) app.num(11,4)]);
                %se1=strel('line',5,90);
                I_canny_di=imdilate(I_canny,se);
                I_canny_di(:,1:2)=1;
                I_canny_di(:,app.M-1:app.M)=1;
                finalimg=zeros(app.M,app.N);
                while(1)
                    if app.f==1
                        app.f=0;
                        app.ii=app.ii-1;
                        break
                    end  
                    app.ii=app.ii+1;
                    [r_contour,contoury,contourx]=lwcontour(I_canny_di,app.I(:,:,app.n1),1,1000);
                    if app.del2==1
                       app.ii=app.ii-1;                   
                       delete(app.ff)          
                       app.IRFp{1,app.ii}=[];
                       finalimg=finalimg-bw;
                       app.del2=0;                   
                    end
                    app.NumberofObjectsEditField.Value=app.ii;
                    contourx=smooth(contourx,5);
                    contoury=smooth(contoury,5);
                    imshow(app.I(:,:,app.n1),'parent',app.UIAxes4);
                    hold(app.UIAxes4,'on');
                    app.ff= plot(app.UIAxes4,contoury,contourx,'r','linewidth',2);
                    bw=roipoly(app.I(:,:,app.n1),contoury,contourx);
                    app.z.contourx=contourx;
                    app.z.contoury=contoury;
                    app.z.mask=bw;
                    app.IRFp{1,app.ii}=app.z;             
                    finalimg=finalimg+bw;
                end
                    app.NumberofObjectsEditField.Value=app.ii;
                    app.IRFp{1,app.ii+1}=finalimg;
                    IRF=app.IRFp;
                    name=strcat(app.file,'/fluid/semi-auto/','Bscan-num',num2str(app.n1));
                    mkdir(name)
                    name1=strcat(name,'/IRF.mat');
                    save(name1, 'IRF')

                    for k=1:app.ii
                     hold(app.UIAxes4,'on');
                     plot(app.UIAxes4,app.IRFp{1,k}.contoury,app.IRFp{1,k}.contourx,'r','linewidth',2);
                    end
                    
                    
                    fig = figure('Visible', false);
                    copyUIAxes(app.UIAxes4,fig);
                    axis off
                    saveas(fig, fullfile(name, 'final image with IRF_semi-auto'), 'jpg');
                    delete(fig);
                    
            end
        end

        % Value changed function: SRFCheckBox
        function SRFCheckBoxValueChanged(app, event)
            value = app.SRFCheckBox.Value;
            if value==true
                app.SRFp=[];
                imshow(app.I(:,:,app.n1),'parent',app.UIAxes4);
                app.NumberofObjectsEditField.Value=0;
                app.ii=0; 
                
                I_denoise=app.I;
                I_denoise(:,:,app.n1)=medfilt2(app.I(:,:,app.n1));
                I_denoise(:,:,app.n1)=imadjust(app.I(:,:,app.n1),[0.3 0.7],[0.1 0.9],0.8);
                
                I_canny=edge(I_denoise(:,:,app.n1),'canny',app.num(11,6),app.num(11,7));
                se=strel('rectangle',[app.num(11,3) app.num(11,4)]);
                %se1=strel('line',5,90);
                I_canny_di=imdilate(I_canny,se);
                I_canny_di(:,1:2)=1;
                I_canny_di(:,app.M-1:app.M)=1;
                finalimg=zeros(app.M,app.N);
                while(1)
                   if app.f==1
                      app.f=0;
                      app.ii=app.ii-1;
                      break
                  end  
                  app.ii=app.ii+1;
                  [r_contour,contoury,contourx]=lwcontour(I_canny_di,app.I(:,:,app.n1),1,1000);  
                  if app.del2==1
                     app.ii=app.ii-1;                   
                     delete(app.ff)          
                     app.SRFp{1,app.ii}=[];
                     finalimg=finalimg-bw;
                     app.del2=0;                   
                 end
                 app.NumberofObjectsEditField.Value=app.ii;
                 contourx=smooth(contourx,5);
                 contoury=smooth(contoury,5);
                imshow(app.I(:,:,app.n1),'parent',app.UIAxes4);
                hold(app.UIAxes4,'on');
                plot(app.UIAxes4,contoury,contourx,'r','linewidth',2)
                bw=roipoly(app.I(:,:,app.n1),contoury,contourx);
                app.z.contourx=contourx;
                app.z.contoury=contoury;
                app.z.mask=bw;
                app.SRFp{1,app.ii}=app.z;             
                finalimg=finalimg+bw;
                end
                app.NumberofObjectsEditField.Value=app.ii;
                app.SRFp{1,app.ii+1}=finalimg;
                SRF=app.SRFp;
                name=strcat(app.file,'/fluid/semi-auto/','Bscan-num',num2str(app.n1));
                mkdir(name)
                name1=strcat(name,'/SRF.mat');
                save(name1, 'SRF')
                
%                 uisave('SRF','SRF.mat');         
               for k=1:app.ii
                   hold(app.UIAxes4,'on');
                   plot(app.UIAxes4,app.SRFp{1,k}.contoury,app.SRFp{1,k}.contourx,'r','linewidth',2);
               end
               
                              
                fig = figure('Visible', false);
                copyUIAxes(app.UIAxes4,fig);
                axis off
                saveas(fig, fullfile(name, 'final image with SRF_semi-auto'), 'jpg');
                delete(fig);
                
            end 
            
        end

        % Value changed function: PEDCheckBox
        function PEDCheckBoxValueChanged(app, event)
            value = app.PEDCheckBox.Value;
            
            if value==true
                app.PEDp=[];
                imshow(app.I(:,:,app.n1),'parent',app.UIAxes4);
                app.NumberofObjectsEditField.Value=0;
                app.ii=0; 
                
                I_denoise=app.I;
                I_denoise(:,:,app.n1)=medfilt2(app.I(:,:,app.n1));
                I_denoise(:,:,app.n1)=imadjust(app.I(:,:,app.n1),[0.3 0.7],[0.1 0.9],0.8);
                
                I_canny=edge(I_denoise(:,:,app.n1),'canny',app.num(11,6),app.num(11,7));
                se=strel('rectangle',[app.num(11,3) app.num(11,4)]);
                %se1=strel('line',5,90);
                I_canny_di=imdilate(I_canny,se);
                I_canny_di(:,1:2)=1;
                I_canny_di(:,app.M-1:app.M)=1;
                finalimg=zeros(app.M,app.N);
                while(1)
                    
                 if app.f==1
                     app.f=0;
                     app.ii=app.ii-1;
                     
                     break
                 end  
                 
                 app.ii=app.ii+1;
                [r_contour,contoury,contourx]=lwcontour(I_canny_di,app.I(:,:,app.n1),1,1000);  
               
                if app.del2==1
                   app.ii=app.ii-1;                   
                   delete(app.ff)          
                   app.PEDp{1,app.ii}=[];
                   app.del2=0;
                   finalimg=finalimg-bw;
               end
               
                
                app.NumberofObjectsEditField.Value=app.ii;
                contourx=smooth(contourx,5);
                contoury=smooth(contoury,5);
                
                imshow(app.I(:,:,app.n1),'parent',app.UIAxes4);
                hold(app.UIAxes4,'on');
                plot(app.UIAxes4,contoury,contourx,'r','linewidth',2)
                bw=roipoly(app.I(:,:,app.n1),contoury,contourx);
                app.z.contourx=contourx;
                app.z.contoury=contoury;
                app.z.mask=bw;
                app.PEDp{1,app.ii}=app.z;             
                finalimg=finalimg+bw;
                end
                app.NumberofObjectsEditField.Value=app.ii;
                app.PEDp{1,app.ii+1}=finalimg;
                PED=app.PEDp;
                name=strcat(app.file,'/fluid/semi-auto/','Bscan-num',num2str(app.n1));
                mkdir(name)
                name1=strcat(name,'/PED.mat');
                save(name1, 'PED')
     
    %             uisave('PED','PED.mat');         
               
                for k=1:app.ii
                 hold(app.UIAxes4,'on');
                 plot(app.UIAxes4,app.PEDp{1,k}.contoury,app.PEDp{1,k}.contourx,'r','linewidth',2);
                end
                          
                fig = figure('Visible', false);
                copyUIAxes(app.UIAxes4,fig);
                axis off
                saveas(fig, fullfile(name, 'final image with PED fluid_semi-auto'), 'jpg');
                delete(fig);
            
            end 
     

        end

        % Button pushed function: ShowAllLayersandSaveButton_2
        function ShowAllLayersandSaveButton_2Pushed(app, event)
           app.contourx_final=[app.contourxn1';app.contourxn2';app.contourxn3';app.contourxn4';app.contourxn5';app.contourxn6';app.contourxn7';app.contourxn8';app.contourxn9'];
            hold(app.UIAxes7, 'on');
            imshow(app.I(:,:,app.p),'Parent',app.UIAxes7)
            for k=1:8 
             for j=1:app.N
                 if app.contourx_final(k,j)>app.contourx_final(k+1,j) || app.contourx_final(k,j)==app.contourx_final(k+1,j)
                    app.contourx_final(k+1,j)=app.contourx_final(k,j)+1;
                end
             end
            end

            plot(app.UIAxes7,1:app.N,app.contourx_final(1,:),'g','LineWidth',0.5);
            plot(app.UIAxes7,1:app.N,app.contourx_final(2,:),'y','LineWidth',0.5);
            plot(app.UIAxes7,1:app.N,app.contourx_final(3,:),'b','LineWidth',0.5);
            plot(app.UIAxes7,1:app.N,app.contourx_final(4,:),'m','LineWidth',0.5);
            plot(app.UIAxes7,1:app.N,app.contourx_final(5,:),'r','LineWidth',0.5);
            plot(app.UIAxes7,1:app.N,app.contourx_final(6,:),'g','LineWidth',0.5);
            plot(app.UIAxes7,1:app.N,app.contourx_final(7,:),'color','red','LineWidth',0.5);
            plot(app.UIAxes7,1:app.N,app.contourx_final(8,:),'color','y','LineWidth',0.5);
            plot(app.UIAxes7,1:app.N,app.contourx_final(9,:),'c','LineWidth',0.5); 
            finalLayer_semiauto= app.contourx_final;
            image=app.I(:,:,app.p);
%            uisave({'finalLayer_semiauto','image'},'layerinfo_semiauto.mat');
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name1=strcat(name,'/Alllayers_info.mat');
            name2=strcat(name,'/image.mat');
            save(name1, 'finalLayer_semiauto')
            save(name2, 'image')
            
            fig = figure('Visible', false);
            copyUIAxes(app.UIAxes7,fig);
            axis off
            saveas(fig, fullfile(name, 'final image with layers'), 'jpg');
            delete(fig);
           
        end

        % Button pushed function: Boundary6Button
        function Boundary6ButtonPushed(app, event)
                %1.live wire on layer6 
                [Gx,Gy]=imgradientxy(app.Ig,'intermediate');
                [r_contour6,contoury6,contourx6] = lwcontour(Gy,app.Ig,1,500);
                [pcontouryn6,app.pcontourxn6]=resize_contour(contoury6,contourx6,app.num(20,1),app.Ig);
                imshow(app.Ig,'parent',app.UIAxes5);
                hold(app.UIAxes5,'on'); 
                plot(app.UIAxes5,pcontouryn6,app.pcontourxn6,'c','LineWidth',0.5);
                [app.Ig2,app.d]=alignment(1,app.Ig,app.pcontourxn6);
                [Gx2,Gy2]=imgradientxy(app.Ig2);
                [mag2,dir2]=imgradient(Gx2,Gy2);
                 %2.live wire on layer6
        end

        % Button pushed function: Boundary3Button
        function Boundary3ButtonPushed(app, event)
                %1.live wire on layer3
                canny=edge(app.Ig2,'canny',[app.num(17,6) app.num(17,7)],'horizontal');
                [r_contour3,contoury3,contourx3]=lwcontour(canny,app.Ig2,1,1000);
                [pcontouryn3,app.pcontourxn3]=resize_contour(contoury3,contourx3,app.num(17,1),app.Ig);
                imshow(app.Ig2,'parent',app.UIAxes5)
                hold(app.UIAxes5,'on'),plot(app.UIAxes5,pcontouryn3,app.pcontourxn3,'color','m');
                %2.live wire on layer3

        end

        % Button pushed function: VeinDetectionButton
        function VeinDetectionButtonPushed(app, event)
                app.Ig1=find_vein(app.Ig,app.pcontourxn3,1:app.N,app.Ig2);
                imshow(app.Ig1,'parent',app.UIAxes5);
        end

        % Button pushed function: Boundary2Button
        function Boundary2ButtonPushed(app, event)
                se=strel('line',app.num(16,3),app.num(16,4));
                Ig1_canny=edge(app.Ig1,'canny',[app.num(16,6) app.num(16,7)],'horizontal');
                Ig1_canny_di=imdilate(Ig1_canny,se);
                figure,[r_contour2,contoury2,contourx2] = lwcontour(Ig1_canny_di,app.Ig1,1,1000);
                [pcontouryn2,app.pcontourxn2]=resize_contour(contoury2,contourx2,app.num(16,1),app.Ig);
                imshow(app.Ig1,'parent',app.UIAxes5);
                hold(app.UIAxes5,'on') 
                plot(app.UIAxes5,pcontouryn2,app.pcontourxn2,'y','LineWidth',0.5);  
        end

        % Button pushed function: Boundary4Button
        function Boundary4ButtonPushed(app, event)
                 [Gx1,Gy1]=imgradientxy(app.Ig1);
                [mag1,dir1]=imgradient(Gx1,Gy1);
                se=strel('line',app.num(18,3),app.num(18,4));
                dir1n=abs(dir1./max(dir1(:))-1);
                dir1n_er=imerode(dir1n,se);
                [r_contour4,contoury4,contourx4]=lwcontour(dir1n_er,app.Ig1,1,1000);
                [pcontouryn4,app.pcontourxn4]=resize_contour(contoury4,contourx4,app.num(18,1),app.Ig);
                imshow(app.Ig1,'parent',app.UIAxes5)
                hold(app.UIAxes5,'on'),plot(app.UIAxes5,pcontouryn4,app.pcontourxn4,'color','red');  
                

        end

        % Button pushed function: Boundary3_finalButton
        function Boundary3_finalButtonPushed(app, event)
                %1.live wire on layer3
                canny=edge(app.Ig1,'canny',[app.num(17,6) app.num(17,7)],'horizontal');
                se=strel('line',app.num(17,3),app.num(17,4));
                canny_di=imdilate(canny,se);            
                canny_dibb=black(round(app.pcontourxn4'-20),1:app.N,canny_di);
                [r_contour3,contoury3,contourx3]=lwcontour(canny_dibb,app.Ig1,1,1000);
                [pcontouryn3,app.pcontourxn3]=resize_contour(contoury3,contourx3,12,app.Ig);
                imshow(app.Ig1,'parent',app.UIAxes5)
                hold(app.UIAxes5,'on'),plot(app.UIAxes5,pcontouryn3,app.pcontourxn3,'color','m');
                %2.live wire on layer3

        end

        % Button pushed function: Boundary5Button
        function Boundary5ButtonPushed(app, event)
                Ig1_canny=edge(app.Ig1,'canny',[app.num(19,6) app.num(19,7)],'horizontal');
                se=strel('line',app.num(19,3),app.num(19,4));
                canny_di=imdilate(Ig1_canny,se);
                canny_dib=blackl(round((app.pcontourxn4)'),1:app.N,canny_di);
                canny_dibb=blackl(round((app.pcontourxn3)'),1:app.N,canny_dib);
                [r_contour5,contoury5,contourx5]=lwcontour(canny_dibb,app.Ig1,1,1000);
                [pcontouryn5,app.pcontourxn5]=resize_contour(contoury5,contourx5,app.num(19,1),app.Ig);
                imshow(app.Ig1,'parent',app.UIAxes5)
                hold(app.UIAxes5,'on'),plot(app.UIAxes5,pcontouryn5,app.pcontourxn5,'color','green');

        end

        % Button pushed function: Boundary7Button
        function Boundary7ButtonPushed(app, event)
                [Gx1,Gy1]=imgradientxy(app.Ig1);
                [mag1,dir1]=imgradient(Gx1,Gy1);
                dir1black=blackl(round((app.pcontourxn6+app.d)'),1:app.N,dir1);
                [r_contour7,contoury7,contourx7] = lwcontour(dir1black,app.Ig1,1,500);
                [pcontouryn7,app.pcontourxn7]=resize_contour(contoury7,contourx7,app.num(21,1),app.Ig);
                imshow(app.Ig1,'parent',app.UIAxes5)
                hold(app.UIAxes5,'on'),plot(app.UIAxes5,pcontouryn7,app.pcontourxn7,'r','LineWidth',0.5); 

        end

        % Button pushed function: Boundary8Button
        function Boundary8ButtonPushed(app, event)
                [Gx1,Gy1]=imgradientxy(app.Ig1);
                [mag1,dir1]=imgradient(Gx1,Gy1);
                dir1b=blackl(round((app.pcontourxn6+app.d)'),1:app.N,dir1);
                dir1bb=blackl(round((app.pcontourxn7)'),1:app.N,dir1b);
                figure
                %[r_contour8,contoury8,contourx8]=lwcontour(dir1bb,app.Ig1,1,500);
               
                [r_contour8,contoury8,contourx8] = lwcontour(app.Ig1,app.Ig1,1,500);
                [pcontouryn8,app.pcontourxn8]=resize_contour(contoury8,contourx8,app.num(22,1),app.Ig);
                imshow(app.Ig1,'parent',app.UIAxes5)
                hold(app.UIAxes5,'on'),plot(app.UIAxes5,pcontouryn8,app.pcontourxn8,'c','LineWidth',0.5); 
                

        end

        % Button pushed function: Boundary1Button
        function Boundary1ButtonPushed(app, event)
                Ig_canny=edge(app.Ig,'canny',app.num(15,6),app.num(15,7));
                [r_contour1,contoury1,contourx1]=lwcontour(Ig_canny,app.Ig,1,1000);
                [pcontouryn1,app.pcontourxn1]=resize_contour(contoury1,contourx1,app.num(15,1),app.Ig);
                imshow(app.Ig,'parent',app.UIAxes5);
                hold(app.UIAxes5,'on') ,plot(app.UIAxes5,pcontouryn1,app.pcontourxn1,'color','g');

        end

        % Button pushed function: ShowAllLayersandSaveButton_3
        function ShowAllLayersandSaveButton_3Pushed(app, event)
             pcontourx_final=[app.pcontourxn1';smooth(app.pcontourxn2-app.d,10)';smooth(app.pcontourxn3-app.d,20)';smooth(app.pcontourxn4-app.d,20)';smooth(app.pcontourxn5-app.d,20)';app.pcontourxn6';(smooth(app.pcontourxn7-app.d,40))';(smooth(app.pcontourxn8-app.d,40))'];
            
            for k=1:7 
             for j=1:app.N
             if pcontourx_final(k,j)>pcontourx_final(k+1,j) || pcontourx_final(k,j)==pcontourx_final(k+1,j)
                 pcontourx_final(k+1,j)=pcontourx_final(k,j)+1;
             end
             end
            end
            [app.Ig3,app.d]=alignment(-1,app.Ig1,app.pcontourxn6);
            imshow(app.Ig3,'parent',app.UIAxes5)
            hold(app.UIAxes5,'on'),plot(app.UIAxes5,1:app.N,pcontourx_final(1,:),'color','c');
            hold(app.UIAxes5,'on'),plot(app.UIAxes5,1:app.N,pcontourx_final(2,:),'color','y');
            hold(app.UIAxes5,'on'),plot(app.UIAxes5,1:app.N,pcontourx_final(3,:),'color','red');
            hold(app.UIAxes5,'on'),plot(app.UIAxes5,1:app.N,pcontourx_final(4,:),'color','green');
            hold(app.UIAxes5,'on'),plot(app.UIAxes5,1:app.N,pcontourx_final(5,:),'color','c');
            hold(app.UIAxes5,'on'),plot(app.UIAxes5,1:app.N,pcontourx_final(6,:),'color','[0.1 0.6 0.4]');
            hold(app.UIAxes5,'on'),plot(app.UIAxes5,1:app.N,pcontourx_final(7,:),'y');
            hold(app.UIAxes5,'on'),plot(app.UIAxes5,1:app.N,pcontourx_final(8,:),'m');
            finalLayer_peripapillary=pcontourx_final;
            image=app.Ig;
%             uisave({'finalLayer_peripapillary','image'},'peripapillary_layerinfo.mat');

            name=strcat(app.file,'/peripapillary/');
            mkdir(name)
            name1=strcat(name,'/finalLayer_peripapillary.mat');
            name2=strcat(name,'/image.mat');
            save(name1, 'finalLayer_peripapillary')
            save(name2, 'image')
            
            fig = figure('Visible', false);
            copyUIAxes(app.UIAxes5,fig);
            axis off
            saveas(fig, fullfile(name, 'final peripapillary image with layers'), 'jpg');
            delete(fig);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
%                 app.Ig=imread('E:\karamoozi\pripapillary\1.png');
                s=uiimport('-file');
                s=struct2cell(s);
                app.Ig=s{1};
                
                app.Ig=rgb2gray(app.Ig);
                imshow(app.Ig,'parent',app.UIAxes5)
                [app.M,app.N]=size(app.Ig);                   
                
               app.pcontourxn1=NaN(1,app.N);
               app.pcontourxn2=NaN(1,app.N);
               app.pcontourxn3=NaN(1,app.N);
               app.pcontourxn4=NaN(1,app.N);
               app.pcontourxn5=NaN(1,app.N);
               app.pcontourxn6=NaN(1,app.N);
               app.pcontourxn7=NaN(1,app.N);
               app.pcontourxn8=NaN(1,app.N);
        end

        % Callback function
        function imagenumberEditField_4ValueChanged(app, event)
            value = app.imagenumberEditField_4.Value;
            app.imagenumberEditField_4.Limits = [1 app.K];
            app.n4=value;
            
            imshow(app.I(:,:,app.n4),'parent',app.UIAxes6);
        end

        % Callback function
        function startButtonPushed(app, event)
        
        end

        % Callback function
        function deleteButton_3Pushed(app, event)
            app.u=app.u-1;
            app.r=app.r-app.Phi;
            app.NumberofobjectsEditField_3.Value=app.u;
        end

        % Callback function
        function saveButton_2Pushed(app, event)
       
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
             app.I=imrotate(app.I,270);
             [app.M,app.N,app.K]=size(app.I);
             imshow(app.I(:,:,1),'Parent',app.UIAxes);
%              for j=1:app.K
%              app.I(:,:,j)=medfilt2(app.I(:,:,j));
%              app.I(:,:,j)=imadjust(app.I(:,:,j),[0.3 0.7],[0.1 0.9],0.8);
%              end
        end

        % Value changed function: ImageNumberEditField_2
        function ImageNumberEditField_2ValueChanged(app, event)
            value = app.ImageNumberEditField_2.Value;
            app.ImageNumberEditField_2.Limits = [1 app.K];
            app.p=value;
            imshow(app.I(:,:,app.p),'Parent',app.UIAxes2);
        end

        % Value changed function: ImageNumberEditField
        function ImageNumberEditFieldValueChanged(app, event)
            value = app.ImageNumberEditField.Value;
            app.ImageNumberEditField.Limits = [1 app.K];
            app.p=value;
            imshow(app.I(:,:,app.p),'Parent',app.UIAxes8);
            app.y1=NaN(1,app.N);
            app.y2=NaN(1,app.N);
            app.y3=NaN(1,app.N);
            app.y4=NaN(1,app.N);
            app.y5=NaN(1,app.N);
            app.y6=NaN(1,app.N);
            app.y7=NaN(1,app.N);
            app.y8=NaN(1,app.N);
            app.y9=NaN(1,app.N);
        end

        % Button pushed function: ILMButton_2
        function ILMButton_2Pushed(app, event)
            warning off
            [app.y1]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes8)
            hold(app.UIAxes8,'on'),plot(app.UIAxes8,1:app.N,app.y1,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ILM.mat');
            layer1 = app.y1;
            save(name, 'layer1')
        end

        % Button pushed function: NFLGCLButton_2
        function NFLGCLButton_2Pushed(app, event)
            warning off
            [app.y2]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes8)
            hold(app.UIAxes8,'on'),plot(app.UIAxes8,1:app.N,app.y2,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/NFL-GCL.mat');
            layer2 = app.y2;
            save(name, 'layer2')
        end

        % Button pushed function: GCLIPLButton_4
        function GCLIPLButton_4Pushed(app, event)
            warning off
            [app.y3]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes8)
            hold(app.UIAxes8,'on'),plot(app.UIAxes8,1:app.N,app.y3,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/IPL-INL.mat');
            layer3 = app.y3;
            save(name, 'layer3')
        end

        % Button pushed function: IPLINLButton_2
        function IPLINLButton_2Pushed(app, event)
            warning off
            [app.y4]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes8)
            hold(app.UIAxes8,'on'),plot(app.UIAxes8,1:app.N,app.y4,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/GCL-IPL.mat');
            layer4 = app.y4;
            save(name, 'layer4')
        end

        % Button pushed function: INLOPLButton_2
        function INLOPLButton_2Pushed(app, event)
            warning off
            [app.y5]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes8)
            hold(app.UIAxes8,'on'),plot(app.UIAxes8,1:app.N,app.y5,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/INL-OPL.mat');
            layer5 = app.y5;
            save(name, 'layer5')
        end

        % Button pushed function: OPLONLButton_2
        function OPLONLButton_2Pushed(app, event)
            warning off
            [app.y6]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes8)
            hold(app.UIAxes8,'on'),plot(app.UIAxes8,1:app.N,app.y6,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/OPL-ONL.mat');
            layer6 = app.y6;
            save(name, 'layer6')
        end

        % Button pushed function: ONLISOSButton_2
        function ONLISOSButton_2Pushed(app, event)
            warning off
            [app.y7]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes8)
            hold(app.UIAxes8,'on'),plot(app.UIAxes8,1:app.N,app.y7,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ONL-ISOS.mat');
            layer7 = app.y7;
            save(name, 'layer7')
        end

        % Button pushed function: ISOSRPEButton_2
        function ISOSRPEButton_2Pushed(app, event)
            warning off
            [app.y8]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes8)
            hold(app.UIAxes8,'on'),plot(app.UIAxes8,1:app.N,app.y8,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ISOS-RPE.mat');
            layer8 = app.y8;
            save(name, 'layer8')
        end

        % Button pushed function: BMChoroidButton_2
        function BMChoroidButton_2Pushed(app, event)
            warning off
            [app.y9]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes8)
            hold(app.UIAxes8,'on'),plot(app.UIAxes8,1:app.N,app.y9,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/BM-Choroid.mat');
            layer9 = app.y9;
            save(name, 'layer9')
        end

        % Button pushed function: ShowAllLayersandSaveButton
        function ShowAllLayersandSaveButtonPushed(app, event)
            app.contoury_final=[app.y1;app.y2;app.y3;app.y4;app.y5;app.y6;app.y7;app.y8;app.y9];
            hold(app.UIAxes8, 'on');
            imshow(app.I(:,:,app.p),'Parent',app.UIAxes8)
            for k=1:8 
             for j=1:app.N
                 if app.contoury_final(k,j)>app.contoury_final(k+1,j) || app.contoury_final(k,j)==app.contoury_final(k+1,j)
                    app.contoury_final(k+1,j)=app.contoury_final(k,j)+1;
                end
             end
            end

            plot(app.UIAxes8,1:app.N,app.contoury_final(1,:),'g','LineWidth',0.5);
            plot(app.UIAxes8,1:app.N,app.contoury_final(2,:),'y','LineWidth',0.5);
            plot(app.UIAxes8,1:app.N,app.contoury_final(3,:),'b','LineWidth',0.5);
            plot(app.UIAxes8,1:app.N,app.contoury_final(4,:),'m','LineWidth',0.5);
            plot(app.UIAxes8,1:app.N,app.contoury_final(5,:),'r','LineWidth',0.5);
            plot(app.UIAxes8,1:app.N,app.contoury_final(6,:),'g','LineWidth',0.5);
            plot(app.UIAxes8,1:app.N,app.contoury_final(7,:),'color','red','LineWidth',0.5);
            plot(app.UIAxes8,1:app.N,app.contoury_final(8,:),'color','y','LineWidth',0.5);
            plot(app.UIAxes8,1:app.N,app.contoury_final(9,:),'c','LineWidth',0.5); 
            finalLayer_freehand= app.contoury_final;
            image=app.I(:,:,app.p);
%             uisave({'finalLayer_freehand','image'},'layerinfo_freehand.mat');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name1=strcat(name,'/Alllayers_info.mat');
            name2=strcat(name,'/image.mat');
            save(name1, 'finalLayer_freehand')
            save(name2, 'image')
            
            fig = figure('Visible', false);
            copyUIAxes(app.UIAxes8,fig);
            axis off
            saveas(fig, fullfile(name, 'final image with layers'), 'jpg');
            delete(fig);
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            warning off
            value = app.FileNameEditField.Value;
            app.file=value;
            mkdir(app.file);
            mkdir(app.file,'layer/manual')
            mkdir(app.file,'layer/semi-auto')
            mkdir(app.file,'fluid/manual')
            mkdir(app.file,'fluid/semi-auto')
        end

        % Button pushed function: LoadExcelFileButton
        function LoadExcelFileButtonPushed(app, event)
            filename = 'Parameters.xlsx';
            [app.num,txt,raw] = xlsread(filename);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileTab = uitab(app.TabGroup);
            app.FileTab.Title = 'File';
            app.UIAxes = uiaxes(app.FileTab);
            app.Panel_3 = uipanel(app.FileTab);
            app.EditField_2 = uieditfield(app.Panel_3, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Editable = 'off';
            app.ImageNumberSpinnerLabel = uilabel(app.Panel_3);
            app.ImageNumberSpinnerLabel.Text = 'Image Number  ';
            app.ImageNumberSpinner = uispinner(app.Panel_3);
            app.ImageNumberSpinner.Limits = [1 Inf];
            app.ImageNumberSpinner.ValueDisplayFormat = '%.0f';
            app.ImageNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @ImageNumberSpinnerValueChanged, true);
            app.ImageNumberSpinner.Value = 1;
            app.matButton = uibutton(app.Panel_3, 'push');
            app.matButton.ButtonPushedFcn = createCallbackFcn(app, @matButtonPushed, true);
            app.matButton.Text = '.mat';
            app.volButton = uibutton(app.Panel_3, 'push');
            app.volButton.ButtonPushedFcn = createCallbackFcn(app, @volButtonPushed, true);
            app.volButton.Text = '.vol';
            app.octbinButton = uibutton(app.Panel_3, 'push');
            app.octbinButton.ButtonPushedFcn = createCallbackFcn(app, @octbinButtonPushed, true);
            app.octbinButton.Text = '.octbin';
            app.FileNameEditFieldLabel = uilabel(app.FileTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.FileTab, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FileNameEditField.Value = 'E:/default';
            app.RotateButton = uibutton(app.FileTab, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.LoadExcelFileButton = uibutton(app.FileTab, 'push');
            app.LoadExcelFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExcelFileButtonPushed, true);
            app.LoadExcelFileButton.Text = 'Load Excel File';
            app.ManualLayerSegmentationTab = uitab(app.TabGroup);
            app.ManualLayerSegmentationTab.Title = 'Manual Layer Segmentation';
            app.BoundariesPanel_2 = uipanel(app.ManualLayerSegmentationTab);
            app.BoundariesPanel_2.Title = 'Boundaries';
            app.ILMButton_2 = uibutton(app.BoundariesPanel_2, 'push');
            app.ILMButton_2.ButtonPushedFcn = createCallbackFcn(app, @ILMButton_2Pushed, true);
            app.ILMButton_2.Text = 'ILM';
            app.NFLGCLButton_2 = uibutton(app.BoundariesPanel_2, 'push');
            app.NFLGCLButton_2.ButtonPushedFcn = createCallbackFcn(app, @NFLGCLButton_2Pushed, true);
            app.NFLGCLButton_2.Text = 'NFL-GCL';
            app.INLOPLButton_2 = uibutton(app.BoundariesPanel_2, 'push');
            app.INLOPLButton_2.ButtonPushedFcn = createCallbackFcn(app, @INLOPLButton_2Pushed, true);
            app.INLOPLButton_2.Text = 'INL-OPL';
            app.OPLONLButton_2 = uibutton(app.BoundariesPanel_2, 'push');
            app.OPLONLButton_2.ButtonPushedFcn = createCallbackFcn(app, @OPLONLButton_2Pushed, true);
            app.OPLONLButton_2.Text = 'OPL-ONL';
            app.ONLISOSButton_2 = uibutton(app.BoundariesPanel_2, 'push');
            app.ONLISOSButton_2.ButtonPushedFcn = createCallbackFcn(app, @ONLISOSButton_2Pushed, true);
            app.ONLISOSButton_2.Text = 'ONL-IS/OS';
            app.ISOSRPEButton_2 = uibutton(app.BoundariesPanel_2, 'push');
            app.ISOSRPEButton_2.ButtonPushedFcn = createCallbackFcn(app, @ISOSRPEButton_2Pushed, true);
            app.ISOSRPEButton_2.Text = 'IS/OS-RPE';
            app.BMChoroidButton_2 = uibutton(app.BoundariesPanel_2, 'push');
            app.BMChoroidButton_2.ButtonPushedFcn = createCallbackFcn(app, @BMChoroidButton_2Pushed, true);
            app.BMChoroidButton_2.Text = 'BM-Choroid';
            app.IPLINLButton_2 = uibutton(app.BoundariesPanel_2, 'push');
            app.IPLINLButton_2.ButtonPushedFcn = createCallbackFcn(app, @IPLINLButton_2Pushed, true);
            app.IPLINLButton_2.Text = 'IPL-INL';
            app.GCLIPLButton_4 = uibutton(app.BoundariesPanel_2, 'push');
            app.GCLIPLButton_4.ButtonPushedFcn = createCallbackFcn(app, @GCLIPLButton_4Pushed, true);
            app.GCLIPLButton_4.Text = 'GCL-IPL';
            app.UIAxes8 = uiaxes(app.ManualLayerSegmentationTab);
            app.ImageNumberEditFieldLabel = uilabel(app.ManualLayerSegmentationTab);
            app.ImageNumberEditFieldLabel.Text = 'Image Number';
            app.ImageNumberEditField = uieditfield(app.ManualLayerSegmentationTab, 'numeric');
            app.ImageNumberEditField.Limits = [0 Inf];
            app.ImageNumberEditField.ValueDisplayFormat = '%.0f';
            app.ImageNumberEditField.ValueChangedFcn = createCallbackFcn(app, @ImageNumberEditFieldValueChanged, true);
            app.ShowAllLayersandSaveButton = uibutton(app.ManualLayerSegmentationTab, 'push');
            app.ShowAllLayersandSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAllLayersandSaveButtonPushed, true);
            app.ShowAllLayersandSaveButton.Text = 'Show All Layers and Save';
            app.AutoLayerSegmentationTab = uitab(app.TabGroup);
            app.AutoLayerSegmentationTab.Title = 'Auto Layer Segmentation ';
            app.TabGroup2 = uitabgroup(app.AutoLayerSegmentationTab);
            app.SemiAutomaticTab = uitab(app.TabGroup2);
            app.SemiAutomaticTab.Title = 'Semi-Automatic';
            app.ImageNumberEditField_5Label = uilabel(app.SemiAutomaticTab);
            app.ImageNumberEditField_5Label.Text = 'Image Number';
            app.ImageNumberEditField_5 = uieditfield(app.SemiAutomaticTab, 'numeric');
            app.ImageNumberEditField_5.Limits = [0 Inf];
            app.ImageNumberEditField_5.ValueDisplayFormat = '%.0f';
            app.ImageNumberEditField_5.ValueChangedFcn = createCallbackFcn(app, @ImageNumberEditField_5ValueChanged, true);
            app.UIAxes1 = uiaxes(app.SemiAutomaticTab);
            app.ShowAllLayersButton = uibutton(app.SemiAutomaticTab, 'push');
            app.ShowAllLayersButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAllLayersButtonPushed, true);
            app.ShowAllLayersButton.Text = 'Show All Layers';
            app.BoundariesPanel = uipanel(app.SemiAutomaticTab);
            app.BoundariesPanel.Title = 'Boundaries';
            app.ILMButton = uibutton(app.BoundariesPanel, 'push');
            app.ILMButton.ButtonPushedFcn = createCallbackFcn(app, @ILMButtonPushed, true);
            app.ILMButton.Text = 'ILM';
            app.NFLGCLButton = uibutton(app.BoundariesPanel, 'push');
            app.NFLGCLButton.ButtonPushedFcn = createCallbackFcn(app, @NFLGCLButtonPushed, true);
            app.NFLGCLButton.Text = 'NFL-GCL';
            app.IPLINLButton = uibutton(app.BoundariesPanel, 'push');
            app.IPLINLButton.ButtonPushedFcn = createCallbackFcn(app, @IPLINLButtonPushed, true);
            app.IPLINLButton.Text = 'IPL-INL';
            app.GCLIPLButton_2 = uibutton(app.BoundariesPanel, 'push');
            app.GCLIPLButton_2.ButtonPushedFcn = createCallbackFcn(app, @GCLIPLButton_2Pushed, true);
            app.GCLIPLButton_2.Text = 'GCL-IPL';
            app.INLOPLButton = uibutton(app.BoundariesPanel, 'push');
            app.INLOPLButton.ButtonPushedFcn = createCallbackFcn(app, @INLOPLButtonPushed, true);
            app.INLOPLButton.Text = 'INL-OPL';
            app.OPLONLButton = uibutton(app.BoundariesPanel, 'push');
            app.OPLONLButton.ButtonPushedFcn = createCallbackFcn(app, @OPLONLButtonPushed, true);
            app.OPLONLButton.Text = 'OPL-ONL';
            app.ONLISOSButton = uibutton(app.BoundariesPanel, 'push');
            app.ONLISOSButton.ButtonPushedFcn = createCallbackFcn(app, @ONLISOSButtonPushed, true);
            app.ONLISOSButton.Text = 'ONL-IS/OS';
            app.ISOSRPEButton = uibutton(app.BoundariesPanel, 'push');
            app.ISOSRPEButton.ButtonPushedFcn = createCallbackFcn(app, @ISOSRPEButtonPushed, true);
            app.ISOSRPEButton.Text = 'IS/OS-RPE';
            app.BMChoroidButton = uibutton(app.BoundariesPanel, 'push');
            app.BMChoroidButton.ButtonPushedFcn = createCallbackFcn(app, @BMChoroidButtonPushed, true);
            app.BMChoroidButton.Text = 'BM-Choroid';
            app.Lamp = uilamp(app.BoundariesPanel);
            app.Lamp2 = uilamp(app.BoundariesPanel);
            app.Lamp3 = uilamp(app.BoundariesPanel);
            app.Lamp4 = uilamp(app.BoundariesPanel);
            app.Lamp5 = uilamp(app.BoundariesPanel);
            app.Lamp6 = uilamp(app.BoundariesPanel);
            app.Lamp7 = uilamp(app.BoundariesPanel);
            app.Lamp9 = uilamp(app.BoundariesPanel);
            app.Lamp8 = uilamp(app.BoundariesPanel);
            app.ManualGridTab = uitab(app.TabGroup2);
            app.ManualGridTab.Title = 'Manual-Grid';
            app.UIAxes2 = uiaxes(app.ManualGridTab);
            app.GridEditFieldLabel = uilabel(app.ManualGridTab);
            app.GridEditFieldLabel.Text = 'Grid ';
            app.GridEditField = uieditfield(app.ManualGridTab, 'numeric');
            app.GridEditField.Limits = [1 50];
            app.GridEditField.ValueChangedFcn = createCallbackFcn(app, @GridEditFieldValueChanged, true);
            app.GridEditField.Value = 10;
            app.Panel = uipanel(app.ManualGridTab);
            app.ILMCheckBox = uicheckbox(app.Panel);
            app.ILMCheckBox.ValueChangedFcn = createCallbackFcn(app, @ILMCheckBoxValueChanged, true);
            app.ILMCheckBox.Text = 'ILM';
            app.NFLGCLCheckBox = uicheckbox(app.Panel);
            app.NFLGCLCheckBox.ValueChangedFcn = createCallbackFcn(app, @NFLGCLCheckBoxValueChanged, true);
            app.NFLGCLCheckBox.Text = 'NFL-GCL';
            app.GCLIPLCheckBox = uicheckbox(app.Panel);
            app.GCLIPLCheckBox.ValueChangedFcn = createCallbackFcn(app, @GCLIPLCheckBoxValueChanged, true);
            app.GCLIPLCheckBox.Text = 'GCL-IPL';
            app.IPLINLCheckBox = uicheckbox(app.Panel);
            app.IPLINLCheckBox.ValueChangedFcn = createCallbackFcn(app, @IPLINLCheckBoxValueChanged, true);
            app.IPLINLCheckBox.Text = 'IPL-INL';
            app.INLOPLCheckBox = uicheckbox(app.Panel);
            app.INLOPLCheckBox.ValueChangedFcn = createCallbackFcn(app, @INLOPLCheckBoxValueChanged, true);
            app.INLOPLCheckBox.Text = 'INL-OPL';
            app.OPLONLCheckBox = uicheckbox(app.Panel);
            app.OPLONLCheckBox.ValueChangedFcn = createCallbackFcn(app, @OPLONLCheckBoxValueChanged, true);
            app.OPLONLCheckBox.Text = 'OPL-ONL';
            app.ONLISOSCheckBox = uicheckbox(app.Panel);
            app.ONLISOSCheckBox.ValueChangedFcn = createCallbackFcn(app, @ONLISOSCheckBoxValueChanged, true);
            app.ONLISOSCheckBox.Text = 'ONL-IS/OS';
            app.ISOSRPECheckBox = uicheckbox(app.Panel);
            app.ISOSRPECheckBox.ValueChangedFcn = createCallbackFcn(app, @ISOSRPECheckBoxValueChanged, true);
            app.ISOSRPECheckBox.Text = 'IS/OS-RPE';
            app.BMChoroidCheckBox = uicheckbox(app.Panel);
            app.BMChoroidCheckBox.ValueChangedFcn = createCallbackFcn(app, @BMChoroidCheckBoxValueChanged, true);
            app.BMChoroidCheckBox.Text = 'BM-Choroid';
            app.ImageNumberEditField_2Label = uilabel(app.ManualGridTab);
            app.ImageNumberEditField_2Label.Text = 'Image Number';
            app.ImageNumberEditField_2 = uieditfield(app.ManualGridTab, 'numeric');
            app.ImageNumberEditField_2.Limits = [0 Inf];
            app.ImageNumberEditField_2.ValueDisplayFormat = '%.0f';
            app.ImageNumberEditField_2.ValueChangedFcn = createCallbackFcn(app, @ImageNumberEditField_2ValueChanged, true);
            app.LayerCorrectionTab = uitab(app.TabGroup2);
            app.LayerCorrectionTab.Title = 'Layer Correction';
            app.UIAxes3 = uiaxes(app.LayerCorrectionTab);
            app.Panel_4 = uipanel(app.LayerCorrectionTab);
            app.ILMCheckBox_2 = uicheckbox(app.Panel_4);
            app.ILMCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ILMCheckBox_2ValueChanged, true);
            app.ILMCheckBox_2.Text = 'ILM';
            app.NFLGCLCheckBox_2 = uicheckbox(app.Panel_4);
            app.NFLGCLCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @NFLGCLCheckBox_2ValueChanged, true);
            app.NFLGCLCheckBox_2.Text = 'NFL-GCL';
            app.GCLIPLCheckBox_2 = uicheckbox(app.Panel_4);
            app.GCLIPLCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @GCLIPLCheckBox_2ValueChanged, true);
            app.GCLIPLCheckBox_2.Text = 'GCL-IPL';
            app.IPLINLCheckBox_2 = uicheckbox(app.Panel_4);
            app.IPLINLCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @IPLINLCheckBox_2ValueChanged, true);
            app.IPLINLCheckBox_2.Text = 'IPL-INL';
            app.INLOPLCheckBox_2 = uicheckbox(app.Panel_4);
            app.INLOPLCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @INLOPLCheckBox_2ValueChanged, true);
            app.INLOPLCheckBox_2.Text = 'INL-OPL';
            app.OPLONLCheckBox_2 = uicheckbox(app.Panel_4);
            app.OPLONLCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @OPLONLCheckBox_2ValueChanged, true);
            app.OPLONLCheckBox_2.Text = 'OPL-ONL';
            app.ONLISOSCheckBox_2 = uicheckbox(app.Panel_4);
            app.ONLISOSCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ONLISOSCheckBox_2ValueChanged, true);
            app.ONLISOSCheckBox_2.Text = 'ONL-IS/OS';
            app.ISOSRPECheckBox_2 = uicheckbox(app.Panel_4);
            app.ISOSRPECheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ISOSRPECheckBox_2ValueChanged, true);
            app.ISOSRPECheckBox_2.Text = 'IS/OS-RPE';
            app.BMChoroidCheckBox_2 = uicheckbox(app.Panel_4);
            app.BMChoroidCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @BMChoroidCheckBox_2ValueChanged, true);
            app.BMChoroidCheckBox_2.Text = 'BM-Choroid';
            app.GridEditField_2Label = uilabel(app.LayerCorrectionTab);
            app.GridEditField_2Label.Text = 'Grid ';
            app.GridEditField_2 = uieditfield(app.LayerCorrectionTab, 'numeric');
            app.GridEditField_2.Limits = [1 50];
            app.GridEditField_2.ValueDisplayFormat = '%.0f';
            app.GridEditField_2.ValueChangedFcn = createCallbackFcn(app, @GridEditField_2ValueChanged, true);
            app.GridEditField_2.Value = 10;
            app.SaveLayerInfoTab = uitab(app.TabGroup2);
            app.SaveLayerInfoTab.Title = 'Save Layer Info';
            app.ShowAllLayersandSaveButton_2 = uibutton(app.SaveLayerInfoTab, 'push');
            app.ShowAllLayersandSaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowAllLayersandSaveButton_2Pushed, true);
            app.ShowAllLayersandSaveButton_2.Text = 'Show All Layers and Save';
            app.UIAxes7 = uiaxes(app.SaveLayerInfoTab);
            app.FluidSegmentationTab = uitab(app.TabGroup);
            app.FluidSegmentationTab.Title = 'Fluid Segmentation';
            app.TabGroup3 = uitabgroup(app.FluidSegmentationTab);
            app.SemiAutomaticTab_2 = uitab(app.TabGroup3);
            app.SemiAutomaticTab_2.Title = 'Semi-Automatic';
            app.UIAxes4 = uiaxes(app.SemiAutomaticTab_2);
            app.Panel_5 = uipanel(app.SemiAutomaticTab_2);
            app.IRFCheckBox = uicheckbox(app.Panel_5);
            app.IRFCheckBox.ValueChangedFcn = createCallbackFcn(app, @IRFCheckBoxValueChanged, true);
            app.IRFCheckBox.Text = 'IRF';
            app.SRFCheckBox = uicheckbox(app.Panel_5);
            app.SRFCheckBox.ValueChangedFcn = createCallbackFcn(app, @SRFCheckBoxValueChanged, true);
            app.SRFCheckBox.Text = 'SRF';
            app.PEDCheckBox = uicheckbox(app.Panel_5);
            app.PEDCheckBox.ValueChangedFcn = createCallbackFcn(app, @PEDCheckBoxValueChanged, true);
            app.PEDCheckBox.Text = 'PED';
            app.DeleteButton_2 = uibutton(app.Panel_5, 'push');
            app.DeleteButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_2Pushed, true);
            app.DeleteButton_2.Text = 'Delete';
            app.FinishButton_2 = uibutton(app.Panel_5, 'push');
            app.FinishButton_2.ButtonPushedFcn = createCallbackFcn(app, @FinishButton_2Pushed, true);
            app.FinishButton_2.Text = 'Finish';
            app.ImageNumberEditField_3Label = uilabel(app.Panel_5);
            app.ImageNumberEditField_3Label.Text = 'Image Number';
            app.ImageNumberEditField_3 = uieditfield(app.Panel_5, 'numeric');
            app.ImageNumberEditField_3.Limits = [0 Inf];
            app.ImageNumberEditField_3.ValueDisplayFormat = '%.0f';
            app.ImageNumberEditField_3.ValueChangedFcn = createCallbackFcn(app, @ImageNumberEditField_3ValueChanged, true);
            app.NumberofObjectsEditFieldLabel = uilabel(app.Panel_5);
            app.NumberofObjectsEditFieldLabel.Text = 'Number of Objects';
            app.NumberofObjectsEditField = uieditfield(app.Panel_5, 'numeric');
            app.NumberofObjectsEditField.Limits = [0 Inf];
            app.NumberofObjectsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofObjectsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofObjectsEditFieldValueChanged, true);
            app.ManualTab = uitab(app.TabGroup3);
            app.ManualTab.Title = 'Manual';
            app.UIAxes4_2 = uiaxes(app.ManualTab);
            app.Panel_6 = uipanel(app.ManualTab);
            app.IRFCheckBox_2 = uicheckbox(app.Panel_6);
            app.IRFCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @IRFCheckBox_2ValueChanged, true);
            app.IRFCheckBox_2.Text = 'IRF';
            app.SRFCheckBox_2 = uicheckbox(app.Panel_6);
            app.SRFCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SRFCheckBox_2ValueChanged, true);
            app.SRFCheckBox_2.Text = 'SRF';
            app.PEDCheckBox_2 = uicheckbox(app.Panel_6);
            app.PEDCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @PEDCheckBox_2ValueChanged, true);
            app.PEDCheckBox_2.Text = 'PED';
            app.DeleteButton = uibutton(app.Panel_6, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.FinishButton = uibutton(app.Panel_6, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.ImageNumberEditField_4Label = uilabel(app.Panel_6);
            app.ImageNumberEditField_4Label.Text = 'Image Number';
            app.ImageNumberEditField_4 = uieditfield(app.Panel_6, 'numeric');
            app.ImageNumberEditField_4.Limits = [0 Inf];
            app.ImageNumberEditField_4.ValueDisplayFormat = '%.0f';
            app.ImageNumberEditField_4.ValueChangedFcn = createCallbackFcn(app, @ImageNumberEditField_4ValueChanged, true);
            app.NumberofObjectsEditField_2Label = uilabel(app.Panel_6);
            app.NumberofObjectsEditField_2Label.Text = 'Number of Objects';
            app.NumberofObjectsEditField_2 = uieditfield(app.Panel_6, 'numeric');
            app.NumberofObjectsEditField_2.Limits = [0 Inf];
            app.NumberofObjectsEditField_2.ValueDisplayFormat = '%.0f';
            app.NumberofObjectsEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberofObjectsEditField_2ValueChanged, true);
            app.PeripapillaryTab = uitab(app.TabGroup);
            app.PeripapillaryTab.Title = 'Peripapillary';
            app.UIAxes5 = uiaxes(app.PeripapillaryTab);
            app.Panel_7 = uipanel(app.PeripapillaryTab);
            app.Boundary6Button = uibutton(app.Panel_7, 'push');
            app.Boundary6Button.ButtonPushedFcn = createCallbackFcn(app, @Boundary6ButtonPushed, true);
            app.Boundary6Button.Text = 'Boundary 6';
            app.Boundary3Button = uibutton(app.Panel_7, 'push');
            app.Boundary3Button.ButtonPushedFcn = createCallbackFcn(app, @Boundary3ButtonPushed, true);
            app.Boundary3Button.Text = 'Boundary 3';
            app.VeinDetectionButton = uibutton(app.Panel_7, 'push');
            app.VeinDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @VeinDetectionButtonPushed, true);
            app.VeinDetectionButton.Text = 'Vein Detection';
            app.Boundary2Button = uibutton(app.Panel_7, 'push');
            app.Boundary2Button.ButtonPushedFcn = createCallbackFcn(app, @Boundary2ButtonPushed, true);
            app.Boundary2Button.Text = 'Boundary 2';
            app.Boundary4Button = uibutton(app.Panel_7, 'push');
            app.Boundary4Button.ButtonPushedFcn = createCallbackFcn(app, @Boundary4ButtonPushed, true);
            app.Boundary4Button.Text = 'Boundary 4';
            app.Boundary3_finalButton = uibutton(app.Panel_7, 'push');
            app.Boundary3_finalButton.ButtonPushedFcn = createCallbackFcn(app, @Boundary3_finalButtonPushed, true);
            app.Boundary3_finalButton.Text = 'Boundary 3_final';
            app.Boundary5Button = uibutton(app.Panel_7, 'push');
            app.Boundary5Button.ButtonPushedFcn = createCallbackFcn(app, @Boundary5ButtonPushed, true);
            app.Boundary5Button.Text = 'Boundary 5';
            app.Boundary7Button = uibutton(app.Panel_7, 'push');
            app.Boundary7Button.ButtonPushedFcn = createCallbackFcn(app, @Boundary7ButtonPushed, true);
            app.Boundary7Button.Text = 'Boundary 7';
            app.Boundary8Button = uibutton(app.Panel_7, 'push');
            app.Boundary8Button.ButtonPushedFcn = createCallbackFcn(app, @Boundary8ButtonPushed, true);
            app.Boundary8Button.Text = 'Boundary 8';
            app.Boundary1Button = uibutton(app.Panel_7, 'push');
            app.Boundary1Button.ButtonPushedFcn = createCallbackFcn(app, @Boundary1ButtonPushed, true);
            app.Boundary1Button.Text = 'Boundary 1';
            app.LoadButton = uibutton(app.Panel_7, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ShowAllLayersandSaveButton_3 = uibutton(app.PeripapillaryTab, 'push');
            app.ShowAllLayersandSaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @ShowAllLayersandSaveButton_3Pushed, true);
            app.ShowAllLayersandSaveButton_3.Text = 'Show All Layers and Save';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end